classdef FINAL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BPMGauge                     matlab.ui.control.Gauge
        BPMGaugeLabel                matlab.ui.control.Label
        RecordImage                  matlab.ui.control.Image
        HeartImage                   matlab.ui.control.Image
        RecordButton                 matlab.ui.control.Button
        RippleKnob                   matlab.ui.control.DiscreteKnob
        RippleKnobLabel              matlab.ui.control.Label
        FilterTypesKnob              matlab.ui.control.DiscreteKnob
        FilterTypesKnobLabel         matlab.ui.control.Label
        OrderoftheFilterKnob         matlab.ui.control.DiscreteKnob
        OrderoftheFilterKnobLabel    matlab.ui.control.Label
        HighpassCutoffFreqEditField  matlab.ui.control.NumericEditField
        HighpassCutoffFreqEditFieldLabel  matlab.ui.control.Label
        LowpassCutoffFreqEditField   matlab.ui.control.NumericEditField
        LowpassCutoffFreqEditFieldLabel  matlab.ui.control.Label
        Filter                       matlab.ui.control.Button
        FilteredsignalofHeartSoundLabel  matlab.ui.control.Label
        FreqencyAnalysisofRawHeartSignalLabel  matlab.ui.control.Label
        RawsignalofHeartSoundLabel   matlab.ui.control.Label
        FilterTypesDropDown          matlab.ui.control.DropDown
        FilterTypesDropDownLabel     matlab.ui.control.Label
        AnalyseButton                matlab.ui.control.Button
        EEGplotfiltered              matlab.ui.control.UIAxes
        EEGFFT                       matlab.ui.control.UIAxes
        EEGplot                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        s;
        save_path = "C:\Users\Asus\Desktop\Yeni klasör\SAMPLING\RECORDING"
        sampling_freq = 499.89;
        plot_Frequency = [];
        filtering = 0;
        EEG;
        time;
        recording = false;
        b;
        a; 
        EEGfiltered;
        heartrate;
        system = 0;
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed(app, event)
             app.s = serialport("COM4", 115200);
               app.HeartImage.ImageSource = 'hearton.svg';
cla(app.EEGplot); cla(app.EEGFFT);
flush(app.s);
try 
    data = readline(app.s); 
catch exception 
end 
frame = 2500; 
app.EEG = zeros(1, 1000000); 
app.time = zeros(1, 1000000) -1;
i = frame; 
plotECG = plot(app.EEGplot, 0, 0);  % Initialize plot with dummy data
plotECGFFT = plot(app.EEGFFT, 0, 0);  % Initialize plot with dummy data
plotEEGfiltered = plot(app.EEGplotfiltered, 0, 0);
 if app.filtering == 0
        switch app.FilterTypesDropDown.Value
            case 'Butterworth'
                switch app.FilterTypesKnob.Value
                    case 'Lowpass'
                        [app.b, app.a] = butter(str2double(app.OrderoftheFilterKnob.Value), app.LowpassCutoffFreqEditField.Value/(9600/2), 'low');
                    case 'Highpass'
                        [app.b, app.a] = butter(str2double(app.OrderoftheFilterKnob.Value), app.HighpassCutoffFreqEditField.Value/(9600/2), 'high');
                    case 'Bandpass'
                        [app.b, app.a] = butter(str2double(app.OrderoftheFilterKnob.Value), [app.HighpassCutoffFreqEditField.Value/(9600/2) app.LowpassCutoffFreqEditField.Value/(9600/2)], 'bandpass');
                    case 'Bandstop'
                        [app.b, app.a] = butter(str2double(app.OrderoftheFilterKnob.Value), [app.HighpassCutoffFreqEditField.Value/(9600/2) app.LowpassCutoffFreqEditField.Value/(9600/2)], 'stop');
                end
            
        end
 end
 
while 1 
    tic 
    for j = 1:40
        i = i + 1;
        data = str2double(strsplit(readline(app.s), ","));
        app.EEG(i) = data(1);
        app.time(i) = data(2);
    end 
    fs = frame * 1000000 / (app.time(i) - app.time(i - frame));
    last_ECG = app.EEG(i - frame + 1:i);
    ECG_normalized = zscore(last_ECG);
    time_vector = linspace(0, 5, frame);
    % Plot the ECG signal
    set(plotECG, 'XData', time_vector, 'YData', ECG_normalized);


     % Plot the one-sided FFT
    fft_result = fft(ECG_normalized);
    fft_result = fft_result(1:frame/2 + 1);  % Keep only the positive frequencies
    freq_vector = linspace(0, 4600/2, frame/2 + 1);
    set(plotECGFFT, 'XData', freq_vector, 'YData', zscore(abs(fft_result)));
    drawnow;  % Update the plot


    toc
    if app.filtering == 1
    EEGfilter = filtfilt(app.b, app.a, last_ECG);
    set(plotEEGfiltered, 'XData', time_vector, 'YData', EEGfilter);
    end


end


        end

        % Callback function
        function RecordButtonPushed(app, event)
            app.recording = true; 
            save('EEG.mat', 'app.EEG');
            app.RecordImage.ImageSource = 'recordon.svg';
        end

        % Button pushed function: Filter
        function FilterButtonPushed(app, event)
            app.filtering = 1;
        
        end

        % Callback function: not associated with a component
        function UIFigureCloseRequest(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EEGplot = uiaxes(app.UIFigure);
            xlabel(app.EEGplot, 'TIme(s)')
            ylabel(app.EEGplot, 'Amplitude(V)')
            zlabel(app.EEGplot, 'Z')
            app.EEGFFT = uiaxes(app.UIFigure);
            xlabel(app.EEGFFT, 'Frequency(Hz)')
            ylabel(app.EEGFFT, 'Amplitude(dB)')
            zlabel(app.EEGFFT, 'Z')
            app.EEGplotfiltered = uiaxes(app.UIFigure);
            xlabel(app.EEGplotfiltered, 'TIme(s)')
            ylabel(app.EEGplotfiltered, 'Amplitude(V)')
            zlabel(app.EEGplotfiltered, 'Z')
            app.AnalyseButton = uibutton(app.UIFigure, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalyseButton.Text = 'Analyse';
            app.FilterTypesDropDownLabel = uilabel(app.UIFigure);
            app.FilterTypesDropDownLabel.Text = 'Filter Types ';
            app.FilterTypesDropDown = uidropdown(app.UIFigure);
            app.FilterTypesDropDown.Items = {'Butterworth', 'Chebyshev', 'Elliptic'};
            app.FilterTypesDropDown.Value = 'Butterworth';
            app.RawsignalofHeartSoundLabel = uilabel(app.UIFigure);
            app.RawsignalofHeartSoundLabel.Text = 'Raw signal of Heart Sound ';
            app.FreqencyAnalysisofRawHeartSignalLabel = uilabel(app.UIFigure);
            app.FreqencyAnalysisofRawHeartSignalLabel.Text = 'Freqency Analysis of Raw Heart Signal ';
            app.FilteredsignalofHeartSoundLabel = uilabel(app.UIFigure);
            app.FilteredsignalofHeartSoundLabel.Text = 'Filtered signal of Heart Sound ';
            app.Filter = uibutton(app.UIFigure, 'push');
            app.Filter.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.Filter.Icon = fullfile(pathToMLAPP, 'filteroff.svg');
            app.Filter.Text = 'Filter';
            app.LowpassCutoffFreqEditFieldLabel = uilabel(app.UIFigure);
            app.LowpassCutoffFreqEditFieldLabel.Text = 'Lowpass Cutoff Freq.';
            app.LowpassCutoffFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowpassCutoffFreqEditField.Limits = [1 4500];
            app.LowpassCutoffFreqEditField.Value = 1;
            app.HighpassCutoffFreqEditFieldLabel = uilabel(app.UIFigure);
            app.HighpassCutoffFreqEditFieldLabel.Text = 'Highpass Cutoff Freq.';
            app.HighpassCutoffFreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighpassCutoffFreqEditField.Limits = [1 4500];
            app.HighpassCutoffFreqEditField.Value = 1;
            app.OrderoftheFilterKnobLabel = uilabel(app.UIFigure);
            app.OrderoftheFilterKnobLabel.Text = 'Order of the Filter';
            app.OrderoftheFilterKnob = uiknob(app.UIFigure, 'discrete');
            app.OrderoftheFilterKnob.Items = {'1', '2', '3', '4', '5'};
            app.OrderoftheFilterKnob.Value = '1';
            app.FilterTypesKnobLabel = uilabel(app.UIFigure);
            app.FilterTypesKnobLabel.Text = 'Filter Types';
            app.FilterTypesKnob = uiknob(app.UIFigure, 'discrete');
            app.FilterTypesKnob.Items = {'Lowpass', 'Highpass', 'Bandpass', 'Bandstop'};
            app.FilterTypesKnob.Value = 'Lowpass';
            app.RippleKnobLabel = uilabel(app.UIFigure);
            app.RippleKnobLabel.Text = 'Ripple';
            app.RippleKnob = uiknob(app.UIFigure, 'discrete');
            app.RippleKnob.Items = {'1', '2', '3', '4', '5', '6'};
            app.RippleKnob.Value = '1';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.Text = 'Record';
            app.HeartImage = uiimage(app.UIFigure);
            app.HeartImage.ImageSource = fullfile(pathToMLAPP, 'heartoff.svg');
            app.RecordImage = uiimage(app.UIFigure);
            app.RecordImage.ImageSource = fullfile(pathToMLAPP, 'recordoff.svg');
            app.BPMGaugeLabel = uilabel(app.UIFigure);
            app.BPMGaugeLabel.Text = 'BPM';
            app.BPMGauge = uigauge(app.UIFigure, 'circular');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FINAL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end