classdef Filters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        HighpasscutoffEditField       matlab.ui.control.NumericEditField
        HighpasscutoffEditFieldLabel  matlab.ui.control.Label
        LowpassCutoffEditField        matlab.ui.control.NumericEditField
        LowpassCutoffEditFieldLabel   matlab.ui.control.Label
        OrderEditField                matlab.ui.control.NumericEditField
        OrderEditFieldLabel           matlab.ui.control.Label
        FilterTypesDropDown           matlab.ui.control.DropDown
        FilterTypesDropDownLabel      matlab.ui.control.Label
        ApplythefilterButton          matlab.ui.control.Button
        UIAxes_4                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
global y; 
global timeVector;
global Fs;
recObj = audiorecorder;
recDuration = 5;
disp("Begin speaking.")
recordblocking(recObj,recDuration);
disp("End of recording.")
y = getaudiodata(recObj);
Fs = 8000;
timeVector = (0:length(y)-1) / 8000;
plot(app.UIAxes,timeVector, y)

% Compute FFT
N = length(y); % Number of samples
frequencies = Fs*(0:(N/2))/N; % Frequency axis for plotting

fft_result = fft(y);
magnitude_spectrum = 2/N * abs(fft_result(1:N/2+1));

% Define the frequency range of interest (0 to 5 kHz)
freq_range = frequencies <= 5000;

plot(app.UIAxes_4,frequencies(freq_range), magnitude_spectrum(freq_range));

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: ApplythefilterButton
        function ApplythefilterButtonPushed(app, event)
            global y; global timeVector; global Fs;
            f = app.OrderEditField.Value;
            lp = app.LowpassCutoffEditField.Value;
            hp = app.HighpasscutoffEditField.Value;
            [b, a] = butter(f, [hp/(Fs/2), lp/(Fs/2)]);
            z = filtfilt(b, a, y);
            plot(app.UIAxes_2, timeVector, z)
          
            % Compute FFT
N = length(z); % Number of samples
frequencies = Fs*(0:(N/2))/N; % Frequency axis for plotting

fft_result = fft(z);
magnitude_spectrum = 2/N * abs(fft_result(1:N/2+1));

% Define the frequency range of interest (0 to 5 kHz)
freq_range = frequencies <= 5000;

plot(app.UIAxes_3,frequencies(freq_range), magnitude_spectrum(freq_range));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Raw Signal of Sound')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'dB')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes.Tag = 'axeso';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Filtered Signal')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'dB')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.Tag = 'axes1';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'FFT of Filtered Singal')
            xlabel(app.UIAxes_3, 'Time')
            ylabel(app.UIAxes_3, 'dB')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.Tag = 'axeso';
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'FFT of Raw Signal')
            xlabel(app.UIAxes_4, 'Time')
            ylabel(app.UIAxes_4, 'dB')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.Tag = 'axeso';
            app.ApplythefilterButton = uibutton(app.UIFigure, 'push');
            app.ApplythefilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplythefilterButtonPushed, true);
            app.ApplythefilterButton.Tag = 'Analyse_button';
            app.ApplythefilterButton.Text = 'Apply the filter';
            app.FilterTypesDropDownLabel = uilabel(app.UIFigure);
            app.FilterTypesDropDownLabel.Text = 'Filter Types ';
            app.FilterTypesDropDown = uidropdown(app.UIFigure);
            app.FilterTypesDropDown.Items = {'Butterworth', 'Besel', 'Gaussian'};
            app.FilterTypesDropDown.Tag = 'Selecten_section';
            app.FilterTypesDropDown.Value = 'Butterworth';
            app.OrderEditFieldLabel = uilabel(app.UIFigure);
            app.OrderEditFieldLabel.Tag = 'orders';
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.UIFigure, 'numeric');
            app.OrderEditField.Limits = [1 5];
            app.OrderEditField.Value = 1;
            app.LowpassCutoffEditFieldLabel = uilabel(app.UIFigure);
            app.LowpassCutoffEditFieldLabel.Text = 'Lowpass Cutoff';
            app.LowpassCutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighpasscutoffEditFieldLabel = uilabel(app.UIFigure);
            app.HighpasscutoffEditFieldLabel.Text = 'Highpass cutoff';
            app.HighpasscutoffEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Filters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end