classdef choose_port < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIfigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        RefreshButton                  matlab.ui.control.Image
        SelectfromavailableportsLabel  matlab.ui.control.Label
        ApplyButton                    matlab.ui.control.Image
        SocketIcon                     matlab.ui.control.Image
        AvailableportsDropDown         matlab.ui.control.DropDown
        SearchingforavailableportsLabel  matlab.ui.control.Label
        WaitingGif                     matlab.ui.control.Image
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    
    methods (Access = private)
        
        function portSearching (app)
            ports = serialportlist('available');
            app.AvailableportsDropDown.Items = ports;
            if isempty(ports)
                app.WaitingGif.ImageSource = 'error.svg';
                app.SearchingforavailableportsLabel.Text = "No available port found!";
            else
                app.WaitingGif.Visible = 0;
                app.SearchingforavailableportsLabel.Visible = 0;
                
                app.SocketIcon.Visible = 1;
                app.SelectfromavailableportsLabel.Visible = 1;
                app.AvailableportsDropDown.Visible = 1;
                app.ApplyButton.Visible = 1;
                app.RefreshButton.Visible = 1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SearchPorts(app, mainapp)
            app.CallingApp = mainapp;
            drawnow
            app.portSearching();
        end

        % Image clicked function: RefreshButton
        function refresh(app, event)
            app.WaitingGif.Visible = 1;
            app.SearchingforavailableportsLabel.Visible = 1;
            
            app.SocketIcon.Visible = 0;
            app.SelectfromavailableportsLabel.Visible = 0;
            app.AvailableportsDropDown.Visible = 0;
            app.ApplyButton.Visible = 0;
            app.RefreshButton.Visible = 0;
            
            drawnow
            app.portSearching();
        end

        % Image clicked function: ApplyButton
        function ChooseCOMPortButtonClicked(app, event)
            updateCOM(app.CallingApp, app.AvailableportsDropDown.Value);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIfigure = uifigure('Visible', 'off');
            app.UIfigure.Name = 'Choose COM Port';
            app.GridLayout = uigridlayout(app.UIfigure);
            app.WaitingGif = uiimage(app.GridLayout);
            app.WaitingGif.ImageSource = 'waiting.gif';
            app.SearchingforavailableportsLabel = uilabel(app.GridLayout);
            app.SearchingforavailableportsLabel.Text = {'Searching for available ports'; '...'};
            app.AvailableportsDropDown = uidropdown(app.GridLayout);
            app.AvailableportsDropDown.Items = {'COM7'};
            app.AvailableportsDropDown.Visible = 'off';
            app.AvailableportsDropDown.Value = 'COM7';
            app.SocketIcon = uiimage(app.GridLayout);
            app.SocketIcon.Visible = 'off';
            app.SocketIcon.ImageSource = 'port.svg';
            app.ApplyButton = uiimage(app.GridLayout);
            app.ApplyButton.ImageClickedFcn = createCallbackFcn(app, @ChooseCOMPortButtonClicked, true);
            app.ApplyButton.Visible = 'off';
            app.ApplyButton.ImageSource = 'filter_on.svg';
            app.SelectfromavailableportsLabel = uilabel(app.GridLayout);
            app.SelectfromavailableportsLabel.Visible = 'off';
            app.SelectfromavailableportsLabel.Text = 'Select from available ports';
            app.RefreshButton = uiimage(app.GridLayout);
            app.RefreshButton.ImageClickedFcn = createCallbackFcn(app, @refresh, true);
            app.RefreshButton.Visible = 'off';
            app.RefreshButton.ImageSource = 'refresh.svg';
            % Show the figure after all components are created
            app.UIfigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = choose_port(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIfigure)

            % Execute the startup function
            runStartupFcn(app, @(app)SearchPorts(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIfigure)
        end
    end
end