classdef finals < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        InformationHeart            matlab.ui.control.Label
        LungImage                   matlab.ui.control.Image
        RespiratoryRate             matlab.ui.control.Label
        RespirationRateLabel        matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        AnalyseButton_2             matlab.ui.control.Button
        FreqencyAnalysisofFilteredHeartSignalLabel  matlab.ui.control.Label
        BPMLabel                    matlab.ui.control.Label
        BPM                         matlab.ui.control.Label
        FilterImage                 matlab.ui.control.Image
        StopImage                   matlab.ui.control.Image
        StopButton                  matlab.ui.control.Button
        RecordImage                 matlab.ui.control.Image
        HeartImage                  matlab.ui.control.Image
        RecordButton                matlab.ui.control.Button
        RippleKnob                  matlab.ui.control.DiscreteKnob
        RippleKnobLabel             matlab.ui.control.Label
        FilterTypesKnob             matlab.ui.control.DiscreteKnob
        FilterTypesKnobLabel        matlab.ui.control.Label
        OrderoftheFilterKnob        matlab.ui.control.DiscreteKnob
        OrderoftheFilterKnobLabel   matlab.ui.control.Label
        HCFEditField                matlab.ui.control.NumericEditField
        HCFEditFieldLabel           matlab.ui.control.Label
        LCFEditField                matlab.ui.control.NumericEditField
        LCFEditFieldLabel           matlab.ui.control.Label
        Filter                      matlab.ui.control.Button
        FilteredsignalofHeartSoundLabel  matlab.ui.control.Label
        FreqencyAnalysisofRawHeartSignalLabel  matlab.ui.control.Label
        RawsignalofHeartSoundLabel  matlab.ui.control.Label
        FilterTypesDropDown         matlab.ui.control.DropDown
        FilterTypesDropDownLabel    matlab.ui.control.Label
        AnalyseButton               matlab.ui.control.Button
        EEGFFT_2                    matlab.ui.control.UIAxes
        EEGplotfiltered             matlab.ui.control.UIAxes
        EEGFFT                      matlab.ui.control.UIAxes
        EEGplot                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)

        
        s = serialport("COM3", 115200);
   
        save_path = "C:\Users\Asus\Desktop\Yeni klasör\SAMPLING\RECORDING"
        
        
        filtering = 0;
        EEG;
        time;
        recording = true;
        b;
        a; 
        EEGfilter;
        heartrate;
        system = 0;
        last_ECG;
        fs;
        bpmvalue;

        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            flush(app.s);
        end

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed(app, event)
            flush(app.s);
            app.LungImage.ImageSource = 'lungoff.svg';
            app.recording = true;

            app.RawsignalofHeartSoundLabel.Text = 'Raw signal of Heart Sound ';
            app.FreqencyAnalysisofRawHeartSignalLabel.Text = 'Freqency Analysis of Raw Heart Signal ';
            app.FilteredsignalofHeartSoundLabel.Text = 'Filtered signal of Heart Sound ';
            app.FreqencyAnalysisofFilteredHeartSignalLabel.Text = 'Freqency Analysis of Filtered Heart Signal ';
            %app.s = serialport("COM4", 115200);
            app.InformationHeart.Text = 'Info: Suggested bandwith for Heart Sound is 0-200Hz';
            
               app.HeartImage.ImageSource = 'hearton.svg';
               app.StopImage.ImageSource = 'stopoff.svg';
cla(app.EEGplot); cla(app.EEGFFT);

try 
    data = readline(app.s); 
catch exception 


end 
frame = 2500; 
app.EEG = zeros(1, 1000000); 
app.time = zeros(1, 1000000);
i = frame;

plotECG = plot(app.EEGplot, 0, 0, 'y');  % Initialize plot with dummy data
plotECGFFT = plot(app.EEGFFT, 0, 0, 'y');  % Initialize plot with dummy data
plotEEGfiltered = plot(app.EEGplotfiltered, 0, 0, 'y');
plotEEGFFT_2 = plot(app.EEGFFT_2, 0, 0, 'y');

 
while app.recording
    tic 
    for j = 1:40
        i = i + 1;
        data = str2double(strsplit(readline(app.s), ","));
        app.EEG(i) = data(1);
        app.time(i) = data(2);  
    end 
    app.fs = frame * 1000000 / (app.time(i) - app.time(i - frame));
    app.last_ECG = app.EEG(i - frame + 1:i);
    ECG_normalized = zscore(app.last_ECG);
    time_vector = linspace(0, 5, frame);
    % Plot the ECG signal
    set(plotECG, 'XData', time_vector, 'YData', ECG_normalized);


     % Plot the one-sided FFT   
    N = 2^12;
    fft_result = normalize(abs(fft(app.last_ECG)));
    fft_result = fft_result(1:frame/2 + 1);  % Keep only the positive frequencies
    freq_vector = (linspace(0, 4600/2, frame/2 + 1));


    freq_range = freq_vector(freq_vector >= 1 & freq_vector <= 1700);
    fft_result = fft_result(freq_vector >= 1 & freq_vector <= 1700);

    set(plotECGFFT, 'XData', freq_range, 'YData', fft_result);
    drawnow;  % Update the plot
    
    
    toc
    if app.filtering == 1
    app.EEGfilter = filtfilt(app.b, app.a, app.last_ECG);
    set(plotEEGfiltered, 'XData', time_vector, 'YData',zscore(app.EEGfilter));
        
    % normalized = normalize(app.EEGfilter);

  eegbpm = app.EEGfilter - movmean(app.EEGfilter, 70);
eegbpm = eegbpm.^3;
eegbpm(eegbpm < 0) = 0;
eegbpm_normalized = [normalize(eegbpm(1:625), 'Range') normalize(eegbpm(626:1250), 'Range') normalize(eegbpm(1251:1875), 'Range') normalize(eegbpm(1876:2500), 'Range')];
[~, locs_bpm] = findpeaks(eegbpm_normalized, 'MinPeakHeight', 0.3);


differnce = mean(diff(locs_bpm));
seconds = mean((differnce*5)/2500);  
bpm = (59/seconds) + 1;
app.bpmvalue = round(mean(round(bpm)));

  app.BPM.Text = num2str(app.bpmvalue);
         N = 2^12;
    fft_result_filter = normalize(abs(fft(normalize(app.EEGfilter), N)));
    fft_result_filter = fft_result_filter(1:frame/2 + 1);  % Keep only the positive frequencies
    freq_vector_filter = (linspace(0, 4600/2, frame/2 + 1));


    freq_range_filter = freq_vector_filter(freq_vector_filter >= 1 & freq_vector_filter <= 1500);
    fft_result_filter = fft_result_filter(freq_vector_filter >= 1 & freq_vector_filter <= 1500);

    set(plotEEGFFT_2, 'XData', freq_range_filter, 'YData', fft_result_filter);
         
    end
    
end


        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            app.recording = false; 
            dataToSave = app.EEGfilter;
            [filename, pathname] = uiputfile('*.mat', 'Save Data As');
            fullFilePath = fullfile(pathname, filename);
            save(fullFilePath, 'dataToSave');
        end

        % Button pushed function: Filter
        function FilterButtonPushed(app, event)
        app.filtering = 1;
        app.FilterImage.ImageSource = 'filteron.svg';
        switch app.FilterTypesDropDown.Value
            case 'Butterworth'
                switch app.FilterTypesKnob.Value 
                    case 'Lowpass'
                        [app.b, app.a] = butter(str2double(app.OrderoftheFilterKnob.Value), app.LCFEditField.Value/(9600/2), 'low');
                    case 'Highpass'
                        [app.b, app.a] = butter(str2double(app.OrderoftheFilterKnob.Value), app.HCFEditField.Value/(7200/2), 'high');
                    case 'Bandpass'
                        [app.b, app.a] = butter(str2double(app.OrderoftheFilterKnob.Value), [app.HCFEditField.Value/(9600/2) app.LCFEditField.Value/(7200/2)], 'bandpass');
                    case 'Bandstop'
                        [app.b, app.a] = butter(str2double(app.OrderoftheFilterKnob.Value), [app.HCFEditField.Value/(9600/2) app.LCFEditField.Value/(7200/2)], 'stop');
                end
            case 'Chebyshev'
                switch app.FilterTypesKnob.Value
                    case 'Lowpass'
                        [app.b, app.a] = cheby1(str2double(app.OrderoftheFilterKnob.Value), str2double(app.RippleKnob.Value), app.LCFEditField.Value/(9600/2));
                    case "Highpass"
                        [app.b, app.a] = cheby1(str2double(app.OrderoftheFilterKnob.Value), str2double(app.RippleKnob.Value),app.HCFEditField.Value/(7200/2), 'high');
                    case 'Bandpass'
                        [app.b, app.a] = cheby1(str2double(app.OrderoftheFilterKnob.Value), str2double(app.RippleKnob.Value), [app.HCFEditField.Value/(9600/2) app.LCFEditField.Value/(7200/2)], 'bandpass');
                    case 'Bandstop'
                        [app.b, app.a] = cheby1(str2double(app.OrderoftheFilterKnob.Value), str2double(app.RippleKnob.Value), [app.HCFEditField.Value/(9600/2) app.LCFEditField.Value/(7200/2)], 'stop');
                end
        end
 
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
           app.recording = false;
           app.filtering = 0;
           app.StopImage.ImageSource = 'stopon.svg';
           app.HeartImage.ImageSource = 'heartoff.svg';
           app.FilterImage.ImageSource = 'filteroff.svg';
           app.LungImage.ImageSource = 'lungoff.svg';
           flush(app.s);
        end

        % Button pushed function: AnalyseButton_2
        function AnalyseButton_2Pushed(app, event)
            app.LungImage.ImageSource = 'lungon.svg';
            app.HeartImage.ImageSource = 'heartoff.svg';
            app.RawsignalofHeartSoundLabel.Text = 'Raw signal of Lung Sound ';
            app.FreqencyAnalysisofRawHeartSignalLabel.Text = 'Freqency Analysis of Raw Lung Signal ';
            app.FilteredsignalofHeartSoundLabel.Text = 'Filtered signal of Lung Sound ';
            app.FreqencyAnalysisofFilteredHeartSignalLabel.Text = 'Freqency Analysis of Filtered Lung Signal ';
            app.InformationHeart.Text = 'Info: Suggested bandwith for Lung Sound is 300-1000Hz';
          

  flush(app.s);
            app.LungImage.ImageSource = 'lungon.svg';
            app.recording = true;

            app.RawsignalofHeartSoundLabel.Text = 'Raw signal of Heart Sound ';
            app.FreqencyAnalysisofRawHeartSignalLabel.Text = 'Freqency Analysis of Raw Heart Signal ';
            app.FilteredsignalofHeartSoundLabel.Text = 'Filtered signal of Heart Sound ';
            app.FreqencyAnalysisofFilteredHeartSignalLabel.Text = 'Freqency Analysis of Filtered Heart Signal ';
            %app.s = serialport("COM4", 115200);
            app.InformationHeart.Text = 'Info: Suggested bandwith for Heart Sound is 0-200Hz';
            
               app.HeartImage.ImageSource = 'heartoff.svg';
               app.StopImage.ImageSource = 'stopoff.svg';
cla(app.EEGplot); cla(app.EEGFFT);

try 
    data = readline(app.s); 
catch exception 


end 
frame = 2500; 
app.EEG = zeros(1, 1000000); 
app.time = zeros(1, 1000000);
i = frame;

plotECG = plot(app.EEGplot, 0, 0, 'y');  % Initialize plot with dummy data
plotECGFFT = plot(app.EEGFFT, 0, 0, 'y');  % Initialize plot with dummy data
plotEEGfiltered = plot(app.EEGplotfiltered, 0, 0, 'y');
plotEEGFFT_2 = plot(app.EEGFFT_2, 0, 0, 'y');

 
while app.recording
    tic 
    for j = 1:40
        i = i + 1;
        data = str2double(strsplit(readline(app.s), ","));
        app.EEG(i) = data(1);
        app.time(i) = data(2);  
    end 
    app.fs = frame * 1000000 / (app.time(i) - app.time(i - frame));
    app.last_ECG = app.EEG(i - frame + 1:i);
    ECG_normalized = zscore(app.last_ECG);
    time_vector = linspace(0, 5, frame);
    % Plot the ECG signal
    set(plotECG, 'XData', time_vector, 'YData', ECG_normalized);


     % Plot the one-sided FFT   
    N = 2^12;
    fft_result = normalize(abs(fft(app.last_ECG)));
    fft_result = fft_result(1:frame/2 + 1);  % Keep only the positive frequencies
    freq_vector = (linspace(0, 4600/2, frame/2 + 1));


    freq_range = freq_vector(freq_vector >= 1 & freq_vector <= 1700);
    fft_result = fft_result(freq_vector >= 1 & freq_vector <= 1700);

    set(plotECGFFT, 'XData', freq_range, 'YData', fft_result);
    drawnow;  % Update the plot
    
    
    toc
    if app.filtering == 1
    app.EEGfilter = filtfilt(app.b, app.a, app.last_ECG);
    set(plotEEGfiltered, 'XData', time_vector, 'YData',zscore(app.EEGfilter));
        
    % normalized = normalize(app.EEGfilter);

    eegbpm = app.EEGfilter - movmean(app.EEGfilter, 70);
    eegbpm = eegbpm .^3;
    eegbpm(eegbpm < 0) = 0; 

    eegbpm_normalized = [normalize(eegbpm(1:625), 'Range') normalize(eegbpm(626:1250), 'Range') normalize(eegbpm(1251:1875), 'Range') normalize(eegbpm(1876:2500), 'Range')];
    [~, locs_bpm] = findpeaks(eegbpm_normalized,'MinPeakDistance', 0.7, 'MinPeakHeight', 0.7);
    
% 'MinPeakHeight', 0.7
%     [~, locs] = findpeaks(normalized, 'MinPeakHeight', 2.3);
%         if(length(locs) > 4 && length(locs) < 18)
        differnce = mean(locs_bpm(end) - locs_bpm(1));
        seconds = (differnce*5)/2500;  
        peaks_second = seconds/length(locs_bpm);
        app.bpmvalue = mean(round(59/peaks_second));
%         end
        app.BPM.Text = num2str(round(app.bpmvalue));
        
         N = 2^12;
    fft_result_filter = normalize(abs(fft(normalize(app.EEGfilter), N)));
    fft_result_filter = fft_result_filter(1:frame/2 + 1);  % Keep only the positive frequencies
    freq_vector_filter = (linspace(0, 4600/2, frame/2 + 1));


    freq_range_filter = freq_vector_filter(freq_vector_filter >= 1 & freq_vector_filter <= 1500);
    fft_result_filter = fft_result_filter(freq_vector_filter >= 1 & freq_vector_filter <= 1500);

    set(plotEEGFFT_2, 'XData', freq_range_filter, 'YData', fft_result_filter);
         
    end
    
end


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.EEGplot = uiaxes(app.UIFigure);
            xlabel(app.EEGplot, 'TIme(s)')
            ylabel(app.EEGplot, 'Amplitude(V)')
            zlabel(app.EEGplot, 'Z')
            colormap(app.EEGplot, 'spring')
            app.EEGFFT = uiaxes(app.UIFigure);
            xlabel(app.EEGFFT, 'Frequency(Hz)')
            ylabel(app.EEGFFT, 'Amplitude(dB)')
            zlabel(app.EEGFFT, 'Z')
            colormap(app.EEGFFT, 'summer')
            app.EEGplotfiltered = uiaxes(app.UIFigure);
            xlabel(app.EEGplotfiltered, 'TIme(s)')
            ylabel(app.EEGplotfiltered, 'Amplitude(V)')
            zlabel(app.EEGplotfiltered, 'Z')
            colormap(app.EEGplotfiltered, 'autumn')
            app.EEGFFT_2 = uiaxes(app.UIFigure);
            xlabel(app.EEGFFT_2, 'Frequency(Hz)')
            ylabel(app.EEGFFT_2, 'Amplitude(dB)')
            zlabel(app.EEGFFT_2, 'Z')
            colormap(app.EEGFFT_2, 'winter')
            app.AnalyseButton = uibutton(app.UIFigure, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalyseButton.Text = 'Analyse';
            app.FilterTypesDropDownLabel = uilabel(app.UIFigure);
            app.FilterTypesDropDownLabel.Text = 'Filter Types ';
            app.FilterTypesDropDown = uidropdown(app.UIFigure);
            app.FilterTypesDropDown.Items = {'Butterworth', 'Chebyshev'};
            app.FilterTypesDropDown.Value = 'Butterworth';
            app.RawsignalofHeartSoundLabel = uilabel(app.UIFigure);
            app.RawsignalofHeartSoundLabel.Text = 'Raw signal of Heart Sound ';
            app.FreqencyAnalysisofRawHeartSignalLabel = uilabel(app.UIFigure);
            app.FreqencyAnalysisofRawHeartSignalLabel.Text = 'Freqency Analysis of Raw Heart Signal ';
            app.FilteredsignalofHeartSoundLabel = uilabel(app.UIFigure);
            app.FilteredsignalofHeartSoundLabel.Text = 'Filtered signal of Heart Sound ';
            app.Filter = uibutton(app.UIFigure, 'push');
            app.Filter.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.Filter.Text = 'Filter';
            app.LCFEditFieldLabel = uilabel(app.UIFigure);
            app.LCFEditFieldLabel.Text = 'LCF';
            app.LCFEditField = uieditfield(app.UIFigure, 'numeric');
            app.LCFEditField.Limits = [1 4500];
            app.LCFEditField.Value = 1;
            app.HCFEditFieldLabel = uilabel(app.UIFigure);
            app.HCFEditFieldLabel.Text = 'HCF';
            app.HCFEditField = uieditfield(app.UIFigure, 'numeric');
            app.HCFEditField.Limits = [1 4500];
            app.HCFEditField.Value = 1;
            app.OrderoftheFilterKnobLabel = uilabel(app.UIFigure);
            app.OrderoftheFilterKnobLabel.Text = 'Order of the Filter';
            app.OrderoftheFilterKnob = uiknob(app.UIFigure, 'discrete');
            app.OrderoftheFilterKnob.Items = {'1', '2', '3', '4', '5'};
            app.OrderoftheFilterKnob.Value = '1';
            app.FilterTypesKnobLabel = uilabel(app.UIFigure);
            app.FilterTypesKnobLabel.Text = 'Filter Types';
            app.FilterTypesKnob = uiknob(app.UIFigure, 'discrete');
            app.FilterTypesKnob.Items = {'Lowpass', 'Highpass', 'Bandpass', 'Bandstop'};
            app.FilterTypesKnob.Value = 'Lowpass';
            app.RippleKnobLabel = uilabel(app.UIFigure);
            app.RippleKnobLabel.Text = 'Ripple';
            app.RippleKnob = uiknob(app.UIFigure, 'discrete');
            app.RippleKnob.Items = {'1', '2', '3', '4', '5', '6'};
            app.RippleKnob.Value = '1';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.HeartImage = uiimage(app.UIFigure);
            app.HeartImage.ImageSource = fullfile(pathToMLAPP, 'heartoff.svg');
            app.RecordImage = uiimage(app.UIFigure);
            app.RecordImage.ImageSource = fullfile(pathToMLAPP, 'recordoff.svg');
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StopImage = uiimage(app.UIFigure);
            app.StopImage.ImageSource = fullfile(pathToMLAPP, 'stopoff.svg');
            app.FilterImage = uiimage(app.UIFigure);
            app.FilterImage.ImageSource = fullfile(pathToMLAPP, 'filteroff.svg');
            app.BPM = uilabel(app.UIFigure);
            app.BPM.Text = '';
            app.BPMLabel = uilabel(app.UIFigure);
            app.BPMLabel.Text = 'BPM : ';
            app.FreqencyAnalysisofFilteredHeartSignalLabel = uilabel(app.UIFigure);
            app.FreqencyAnalysisofFilteredHeartSignalLabel.Text = 'Freqency Analysis of Filtered Heart Signal ';
            app.AnalyseButton_2 = uibutton(app.UIFigure, 'push');
            app.AnalyseButton_2.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButton_2Pushed, true);
            app.AnalyseButton_2.Text = 'Analyse';
            app.Image = uiimage(app.UIFigure);
            app.RespirationRateLabel = uilabel(app.UIFigure);
            app.RespirationRateLabel.Text = 'Respiration Rate: ';
            app.RespiratoryRate = uilabel(app.UIFigure);
            app.RespiratoryRate.Text = '';
            app.LungImage = uiimage(app.UIFigure);
            app.LungImage.ImageSource = fullfile(pathToMLAPP, 'lungoff.svg');
            app.InformationHeart = uilabel(app.UIFigure);
            app.InformationHeart.Text = 'Info: Suggested bandwith for Heart Sound is 0-200Hz';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finals

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end